classdef img_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                       matlab.ui.Figure
        Load                          matlab.ui.control.Button
        Save                          matlab.ui.control.Button
        OriginalImage                 matlab.ui.control.UIAxes
        EditedImage                   matlab.ui.control.UIAxes
        Rotate                        matlab.ui.control.Button
        Gray                          matlab.ui.control.Button
        Crop                          matlab.ui.control.Button
        Spots_Removal                 matlab.ui.control.Button
        Vintage_look                  matlab.ui.control.Button
        Neon                          matlab.ui.control.Button
        Beautify                      matlab.ui.control.Button
        sliderBright                  matlab.ui.control.Slider
        pushbuttonContrastBrightness  matlab.ui.control.Button
        sliderContrast                matlab.ui.control.Slider
        sliderRotate                  matlab.ui.control.Slider
        RotateButton                  matlab.ui.control.Button
        editBright                    matlab.ui.control.EditField
        editContrast                  matlab.ui.control.EditField
        editRotate                    matlab.ui.control.EditField
        Motion                        matlab.ui.control.Button
        Color                         matlab.ui.control.Button
        Negative                      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function img_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to img (see VARARGIN)
            
            % Choose default command line output for img
            handles.output = hObject;
            
            % Update handles structure
            %guidata(hObject, handles);
            %handles.output = hObject;
            % handles = TimePosition(handles);
            handles.fileLoaded = 0;
            handles.fileLoaded2 = 0;
            set(handles.OriginalImage,'Visible','off');
            set(handles.EditedImage,'Visible','off');
            %i will try to add 2 histograms 
            %set(handles.axesHist1,'Visible','off');
            %set(handles.axesHist2,'Visible','off');
            %set(handles.editPath, 'Visible', 'off');
            %set(handles.editSize, 'Visible', 'off');
            %set(handles.editComment, 'Visible', 'off');
            %set(handles.textHist1, 'Visible', 'off');
            %set(handles.textHist2, 'Visible', 'off');
            set(handles.sliderBright, 'Enable', 'off');
            set(handles.sliderContrast, 'Enable', 'off');
            set(handles.sliderRotate, 'Enable', 'off');
            set(handles.editBright,'String', sprintf('%10s:%4.0f%%', 'Brightness', 100*get(handles.sliderBright,'Value')));
            set(handles.editContrast,'String', sprintf('%10s:%4.0f%%', 'Contrast', 100*get(handles.sliderContrast,'Value')));
            set(handles.editRotate,'String', sprintf('%10s:%4.0f', 'Rotate', get(handles.sliderRotate,'Value')));
            
            handles.output = hObject;
            
             guidata(hObject, handles);
            
            % UIWAIT makes img wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            
            global x b;
            x=' ';
        end

        % Button pushed function: Beautify
        function Beautify_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Beautify (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global x b
            t=x;
            shadow_lab = rgb2lab(t);
            max_luminosity = 100;
            L = shadow_lab(:,:,1)/max_luminosity;
            shadow_imadjust = shadow_lab;
            shadow_imadjust(:,:,1) = imadjust(L)*max_luminosity;
            shadow_imadjust = lab2rgb(shadow_imadjust);
            
            shadow_histeq = shadow_lab;
            shadow_histeq(:,:,1) = histeq(L)*max_luminosity;
            shadow_histeq = lab2rgb(shadow_histeq);
            
            b = shadow_lab;
            b(:,:,1) = adapthisteq(L)*max_luminosity;
            b = lab2rgb(b);
            imshow(b);
            x=b;
        end

        % Button pushed function: Color
        function Color_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Color (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if (handles.fileLoaded==1)
                RUN = 1;
            
                while (RUN==1)
            
                    prompt = {'Enter weight for RED (0-200%):','Enter weight for GREEN (0-200%):','Enter weight for BLUE (0-200%):'};
                    dlg_title = 'Enter Color Weight Parameters:';
                    num_lines = 1;
                    def = {'100','100','100'};
                    answer = inputdlg(prompt,dlg_title,num_lines,def);
                    if (isempty(answer))
                        return;
                    end
                    W1 = str2num(answer{1});
                    W2 = str2num(answer{2});
                    W3 = str2num(answer{3});
            
            
                    if ((W1>=0) & (W1<=200)) & ((W2>=0) && (W2<=200)) & ((W3>=0) && (W3<=200))
                        RUN = 0;
                    end
                end
            
                handles.RGB2 = handles.RGB;
                R = double(handles.RGB2(:,:,1));
                G = double(handles.RGB2(:,:,2));
                B = double(handles.RGB2(:,:,3));
            
                R = R * W1 ./ 100;
                G = G * W2 ./ 100;
                B = B * W3 ./ 100;
            
                R(find(R>256)) = 256;
                G(find(G>256)) = 256;
                B(find(B>256)) = 256;
            
                handles.RGB2(:,:,1) = R;
                handles.RGB2(:,:,2) = G;
                handles.RGB2(:,:,3) = B;
            
                axes(handles.EditedImage); imshow(handles.RGB2);
                handles.fileLoaded2 = 1;
                %handles = updateHistograms(handles);
                guidata(hObject, handles);
            else
                h = msgbox('No primary file has been loaded!','Error','error');
            end
        end

        % Button pushed function: Crop
        function Crop_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Crop (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global x b
            t=x;
            promptMessage = sprintf('Drag out a box that you want to copy,\nor click Cancel to quit.');
            titleBarCaption = 'Continue?';
            button = questdlg(promptMessage, titleBarCaption, 'Continue', 'Cancel', 'Continue');
            if strcmpi(button, 'Cancel')
              close(hFig); % Close down the figure - get rid of it.
              return;
            end
            k = waitforbuttonpress;
            point1 = get(gca,'CurrentPoint');    % button down detected
            finalRect = rbbox;                   % return figure units
            point2 = get(gca,'CurrentPoint');    % button up detected
            point1 = point1(1,1:2);              % extract x and y
            point2 = point2(1,1:2);
            p1 = min(point1,point2);             % calculate locations
            offset = abs(point1-point2);
            % Find the coordinates of the box.
            xCoords = [p1(1) p1(1)+offset(1) p1(1)+offset(1) p1(1) p1(1)];
            yCoords = [p1(2) p1(2) p1(2)+offset(2) p1(2)+offset(2) p1(2)];
            x1 = round(xCoords(1));
            x2 = round(xCoords(2));
            y1 = round(yCoords(5));
            y2 = round(yCoords(3));
            width = x2-x1;
            height = y2-y1;
            % The box from rbbox() disappears after drawing, so redraw the box over the image.
            hold on
            %axis manual
            plot(xCoords, yCoords, 'b-'); % redraw in dataspace units
            pause(2)
            % Display the cropped image.
            imgtest = t;
            croppedImage = imcrop(imgtest, [x1, y1, width, height]);
            axes(handles.EditedImage);
            %subplot(2,1,1);
            %imshow(t);
            %subplot(2,1,1);
            imshow(croppedImage);
            
            b=croppedImage;
            x=b;
        end

        % Button pushed function: Gray
        function Gray_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Gray (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global x b
            t=x;
            b=rgb2gray(t);
            imshow(b);
            x=b;
        end

        % Button pushed function: Negative
        function Negative_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to InvColor (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if (handles.fileLoaded==1)
            
                R = double(handles.RGB(:,:,1));
                G = double(handles.RGB(:,:,2));
                B = double(handles.RGB(:,:,3));
            
                handles.RGB2(:,:,1) = 256 - R;
                handles.RGB2(:,:,2) = 256 - G;
                handles.RGB2(:,:,3) = 256 - B;
            
                axes(handles.EditedImage); imshow(handles.RGB2);
                handles.fileLoaded2 = 1;
                %handles = updateHistograms(handles);
                guidata(hObject, handles);
            else
                h = msgbox('No primary file has been loaded!','Error','error');
            end
        end

        % Button pushed function: Load
        function Load_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Load (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global x
            %axes(handles.OriginalImage);
            [FileName,PathName]=uigetfile('*.jpg');
            %if file==0
            %    msgbox('NO FILE SELECTED')
            %else
             x =imread([PathName,FileName]);
            %imshow(x);
            %end
             %[FileName,PathName] = uigetfile({'*.*'},'Load Image File');
            
            if (FileName==0) % cancel pressed
                return;
            end
            
            
            handles.fullPath = [PathName FileName];
            [a, b, Ext] = fileparts(FileName);
            availableExt = {'.bmp','.jpg','.jpeg','.tiff','.png','.gif'};
            FOUND = 0;
            for (i=1:length(availableExt))
                if (strcmpi(Ext, availableExt{i}))
                    FOUND=1;
                    break;
                end
            end
            
            if (FOUND==0)
                h = msgbox('File type not supported!','Error','error');
                return;
            end
            
            set(handles.sliderRotate, 'Enable', 'on');
            set(handles.sliderBright, 'Enable', 'on');
            set(handles.sliderContrast, 'Enable', 'on');
            %set(handles.editPath, 'Visible', 'on');
            %set(handles.editSize, 'Visible', 'on');
            %set(handles.editComment, 'Visible', 'on');
            
            
            info = imfinfo(handles.fullPath);
            if (~isempty(info.Comment))
                % save current image comment (to be used later in image save)
                handles.currentImageComment = info.Comment{1};
            else
                handles.currentImageComment = '';
            end
            
            %set(handles.editSize, 'String', sprintf('SIZE (W x H) : %d x %d', info.Width, info.Height));
            %set(handles.editComment, 'String', sprintf('COMMENT: %s', handles.currentImageComment));
            %set(handles.editPath', 'String', handles.fullPath);
            
            
            RGB = imread(handles.fullPath);
            
            handles.RGB = RGB;
            handles.RGB2 = RGB;
            handles.fileLoaded = 1;
            handles.fileLoaded2 = 0;
            
            set(handles.OriginalImage,'Visible','off'); set(handles.EditedImage,'Visible','off');
            %set(handles.axesHist1,'Visible','off'); set(handles.axesHist2,'Visible','off');
            %set(handles.textHist1, 'Visible', 'off');
            %axes(handles.axesHist2); cla;
            %set(handles.textHist2, 'Visible', 'off');
            
            axes(handles.OriginalImage); cla; imshow(RGB);
            axes(handles.EditedImage); cla;
            
            %handles = updateHistograms(handles);
            
            guidata(hObject, handles);
        end

        % Button pushed function: Motion
        function Motion_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Motion (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if (handles.fileLoaded==1)
            
                [M,N,ttt] = size(handles.RGB);
                D = max(M,N);
            
                RUN = 1;
            
                while (RUN==1)
            
                    prompt = {'Enter Motion Length (0-15%):','Enter Motion Angle (0-360):'};
                    dlg_title = 'Enter Motion Parameters:';
                    num_lines = 1;
                    def = {'1','0'};
                    answer = inputdlg(prompt,dlg_title,num_lines,def);
                    if (isempty(answer))
                        return;
                    end
            
                    M1 = str2num(answer{1})/100;
                    M2 = str2num(answer{2});
            
                    if ((str2num(answer{1})>=0) & (str2num(answer{1})<=15))
                        RUN = 0;
                    end
                end
            
                H = fspecial('motion',D * M1,M2);
                w = waitbar(0, 'Motion filtering ... Please wait ...');
                handles.RGB2(:,:,1) = imfilter(handles.RGB(:,:,1),H,'replicate');
                waitbar(1/3, w);
                handles.RGB2(:,:,2) = imfilter(handles.RGB(:,:,2),H,'replicate');
                waitbar(2/3, w);
                handles.RGB2(:,:,3) = imfilter(handles.RGB(:,:,3),H,'replicate');
                close(w);
                axes(handles.EditedImage); imshow(handles.RGB2);
                handles.fileLoaded2 = 1;
                %handles = updateHistograms(handles);
                guidata(hObject, handles);
            else
                h = msgbox('No primary file has been loaded!','Error','error');
            end
        end

        % Button pushed function: Neon
        function Neon_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Neon (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global x b;
            t=x;
            hsvImage = rgb2hsv(t);
            hsvImage(:,:,1) = rem(hsvImage(:,:,1) * 5, 1);
            b = hsv2rgb(hsvImage);
            x=b;
            imshow(b);
        end

        % Button pushed function: RotateButton
        function RotateButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to RotateButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %global x b;
            
            handles.RGB2 = imrotate(handles.RGB, round(get(handles.sliderRotate,'Value')));
            handles.fileLoaded = 1;
            
            axes(handles.EditedImage); cla; imshow(handles.RGB2);
            
            %handles = updateHistograms(handles);
            
            guidata(hObject, handles);
        end

        % Button pushed function: Rotate
        function Rotate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Rotate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global x b
            t=x;
            b=imrotate(t,90);
            imshow(b);
            x=b;
        end

        % Button pushed function: Save
        function Save_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Save (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global x b
            %t=handles.OriginalImage
            [filename,pathname]=uiputfile('*.jpg','save as');
            name=fullfile(pathname,filename);
            %imwrite(b,name,'jpg');
            imwrite(b,name,"jpg","Quality",100);
        end

        % Callback function
        function Sharpen_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Sharpen (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global x b;
            t=x;
            b = imsharpen(t);
            imshow(b);
            x=b;
        end

        % Button pushed function: Spots_Removal
        function Spots_Removal_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Spots_Removal (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global x b;
            t=x;
            %I=rgb2gray(t);
            %Kaverage = filter2(fspecial('average',3),I)/255;
            b = medfilt2(t);
            imshow(b);
            x=b;
        end

        % Button pushed function: Vintage_look
        function Vintage_look_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Vintage_look (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global x b;
            t=x;
            I=rgb2gray(t);
            b = imnoise(I,'salt & pepper',0.05);
            imshow(b);
            x=b;
        end

        % Button pushed function: pushbuttonContrastBrightness
        function pushbuttonContrastBrightness_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonContrastBrightness (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if (handles.fileLoaded==1)
                handles.RGB2 = changeBrightness(handles.RGB, get(handles.sliderBright, 'Value'), get(handles.sliderContrast, 'Value'));
                axes(handles.EditedImage); imshow(handles.RGB2);
                handles.fileLoaded2 = 1;
                %handles = updateHistograms(handles);
                guidata(hObject, handles);
            else
                h = msgbox('No primary file has been loaded!','Error','error');
            end
        end

        % Value changed function: sliderBright
        function sliderBright_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sliderBright (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            set(handles.editBright,'String', sprintf('%10s:%4.0f%%', 'Brightness', 100*get(handles.sliderBright,'Value')));
        end

        % Value changed function: sliderContrast
        function sliderContrast_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sliderContrast (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            set(handles.editContrast,'String', sprintf('%10s:%4.0f%%', 'Contrast', 100*get(handles.sliderContrast,'Value')));
        end

        % Value changed function: sliderRotate
        function sliderRotate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sliderRotate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            set(handles.editRotate,'String', sprintf('%10s:%4.0f', 'Rotate', round(get(handles.sliderRotate,'Value'))));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Mini-Photoshop';
            app.figure1.Tag = 'figure1';
            app.Load = uibutton(app.figure1, 'push');
            app.Load.ButtonPushedFcn = createCallbackFcn(app, @Load_Callback, true);
            app.Load.Tag = 'Openbutton';
            app.Load.Icon = 'open.png';
            app.Load.Text = 'Open';
            app.Save = uibutton(app.figure1, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @Save_Callback, true);
            app.Save.Tag = 'Savebutton';
            app.Save.Icon = 'save.png';
            app.Save.Text = 'Save';
            app.OriginalImage = uiaxes(app.figure1);
            app.OriginalImage.Tag = 'OriginalImage';
            app.EditedImage = uiaxes(app.figure1);
            title(app.EditedImage, 'Edited')
            app.EditedImage.Tag = 'EditedImage';
            app.Rotate = uibutton(app.figure1, 'push');
            app.Rotate.ButtonPushedFcn = createCallbackFcn(app, @Rotate_Callback, true);
            app.Rotate.Tag = 'Rotate';
            app.Rotate.Icon = 'rotate.png';
            app.Rotate.Text = 'Rotate';
            app.Gray = uibutton(app.figure1, 'push');
            app.Gray.ButtonPushedFcn = createCallbackFcn(app, @Gray_Callback, true);
            app.Gray.Tag = 'Gray';
            app.Gray.Icon = 'B&W.png';
            app.Gray.Text = 'Gray';
            app.Crop = uibutton(app.figure1, 'push');
            app.Crop.ButtonPushedFcn = createCallbackFcn(app, @Crop_Callback, true);
            app.Crop.Tag = 'Crop';
            app.Crop.Icon = 'crop.png';
            app.Crop.Text = 'Crop';
            app.Spots_Removal = uibutton(app.figure1, 'push');
            app.Spots_Removal.ButtonPushedFcn = createCallbackFcn(app, @Spots_Removal_Callback, true);
            app.Spots_Removal.Tag = 'Spots_Removal';
            app.Spots_Removal.Icon = 'clean.png';
            app.Spots_Removal.Text = {'Spots '; 'Removal'};
            app.Vintage_look = uibutton(app.figure1, 'push');
            app.Vintage_look.ButtonPushedFcn = createCallbackFcn(app, @Vintage_look_Callback, true);
            app.Vintage_look.Tag = 'Vintage_look';
            app.Vintage_look.Icon = 'vintage.png';
            app.Vintage_look.Text = 'Vintage ';
            app.Neon = uibutton(app.figure1, 'push');
            app.Neon.ButtonPushedFcn = createCallbackFcn(app, @Neon_Callback, true);
            app.Neon.Tag = 'Neon';
            app.Neon.Icon = 'neon.png';
            app.Neon.Text = 'Neon';
            app.Beautify = uibutton(app.figure1, 'push');
            app.Beautify.ButtonPushedFcn = createCallbackFcn(app, @Beautify_Callback, true);
            app.Beautify.Tag = 'Beautify';
            app.Beautify.Icon = 'beautify.png';
            app.Beautify.Text = 'Beautify';
            app.sliderBright = uislider(app.figure1);
            app.sliderBright.Limits = [-1 1];
            app.sliderBright.MajorTicks = [];
            app.sliderBright.ValueChangedFcn = createCallbackFcn(app, @sliderBright_Callback, true);
            app.sliderBright.MinorTicks = [];
            app.sliderBright.Tag = 'sliderBright';
            app.pushbuttonContrastBrightness = uibutton(app.figure1, 'push');
            app.pushbuttonContrastBrightness.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonContrastBrightness_Callback, true);
            app.pushbuttonContrastBrightness.Tag = 'pushbuttonContrastBrightness';
            app.pushbuttonContrastBrightness.Text = 'Change Brightness Contrast';
            app.sliderContrast = uislider(app.figure1);
            app.sliderContrast.Limits = [-1 1];
            app.sliderContrast.MajorTicks = [];
            app.sliderContrast.ValueChangedFcn = createCallbackFcn(app, @sliderContrast_Callback, true);
            app.sliderContrast.MinorTicks = [];
            app.sliderContrast.Tag = 'sliderContrast';
            app.sliderRotate = uislider(app.figure1);
            app.sliderRotate.Limits = [-360 360];
            app.sliderRotate.MajorTicks = [];
            app.sliderRotate.ValueChangedFcn = createCallbackFcn(app, @sliderRotate_Callback, true);
            app.sliderRotate.MinorTicks = [];
            app.sliderRotate.Tag = 'sliderRotate';
            app.RotateButton = uibutton(app.figure1, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButton_Callback, true);
            app.RotateButton.Tag = 'RotateButton';
            app.RotateButton.Text = 'Rotate Button';
            app.editBright = uieditfield(app.figure1, 'text');
            app.editBright.Tag = 'editBright';
            app.editBright.Editable = 'off';
            app.editBright.Value = 'Bright';
            app.editContrast = uieditfield(app.figure1, 'text');
            app.editContrast.Tag = 'editContrast';
            app.editContrast.Value = 'Contrast';
            app.editRotate = uieditfield(app.figure1, 'text');
            app.editRotate.Tag = 'editRotate';
            app.editRotate.Value = 'Rotate';
            app.Motion = uibutton(app.figure1, 'push');
            app.Motion.ButtonPushedFcn = createCallbackFcn(app, @Motion_Callback, true);
            app.Motion.Tag = 'Motion';
            app.Motion.Icon = 'blur.png';
            app.Motion.Text = 'Motion';
            app.Color = uibutton(app.figure1, 'push');
            app.Color.ButtonPushedFcn = createCallbackFcn(app, @Color_Callback, true);
            app.Color.Tag = 'Color';
            app.Color.Icon = 'color.png';
            app.Color.Text = 'Color';
            app.Negative = uibutton(app.figure1, 'push');
            app.Negative.ButtonPushedFcn = createCallbackFcn(app, @Negative_Callback, true);
            app.Negative.Tag = 'InvColor';
            app.Negative.Icon = 'negative.png';
            app.Negative.Text = 'Negative';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = img_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)img_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end